classdef EEGsonic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGSonicUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ConvertEEGDataMenu              matlab.ui.container.Menu
        ViewEEGHeadsetMenu              matlab.ui.container.Menu
        LoadPresetMenu                  matlab.ui.container.Menu
        SavePresetMenu                  matlab.ui.container.Menu
        SaveDefaultSettingsMenu         matlab.ui.container.Menu
        QuitEEGsonicMenu                matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        BIAPTMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GeneralPanel                    matlab.ui.container.Panel
        SelectSavingDirectoryButton     matlab.ui.control.Button
        SavingDirectoryLabel            matlab.ui.control.Label
        EEGdeviceButtonGroup            matlab.ui.container.ButtonGroup
        EGI129Button                    matlab.ui.control.RadioButton
        ReplayButton                    matlab.ui.control.RadioButton
        FolderNameEditFieldLabel        matlab.ui.control.Label
        FolderNameEditField             matlab.ui.control.EditField
        HeadsetParamTabGroup            matlab.ui.container.TabGroup
        EGI129Tab                       matlab.ui.container.Tab
        DataacquisitionsizeinsecondsEditFieldLabel  matlab.ui.control.Label
        EGI129DataSizeEditField         matlab.ui.control.NumericEditField
        AmplifierIPEditFieldLabel       matlab.ui.control.Label
        EGI129IPEditField               matlab.ui.control.EditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        EGI129SamplingRateEditField     matlab.ui.control.NumericEditField
        ReplayTab                       matlab.ui.container.Tab
        SelectReplayDirectoryButton     matlab.ui.control.Button
        ReplayDirectoryLabel            matlab.ui.control.Label
        OSCReceiversTab                 matlab.ui.container.Tab
        OSCReceiversPanel               matlab.ui.container.Panel
        OSCReceiversListBoxLabel        matlab.ui.control.Label
        OSCReceiversListBox             matlab.ui.control.ListBox
        AddOSCReceiversButton           matlab.ui.control.Button
        RemoveOSCReceiversButton        matlab.ui.control.Button
        PathEditField                   matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        PortEditField                   matlab.ui.control.EditField
        SameMachineCheckBox             matlab.ui.control.CheckBox
        OSCReceiverLabel                matlab.ui.control.Label
        SelectOSCReceiversButton        matlab.ui.control.Button
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditField              matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        SpectralPowerRatioTab           matlab.ui.container.Tab
        SpectralPowerRatioPanel         matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel  matlab.ui.control.Label
        SPRWindowSizeEditField          matlab.ui.control.NumericEditField
        TimeBandwithProductLabel        matlab.ui.control.Label
        SPRTimeBandwithProductEditField  matlab.ui.control.NumericEditField
        RequiredSizeinsecondsLabel      matlab.ui.control.Label
        SPRRequiredSizeEditField        matlab.ui.control.NumericEditField
        NumberofTapersLabel             matlab.ui.control.Label
        SPRNumberTapersEditField        matlab.ui.control.NumericEditField
        ThetaBandwithinHzEditFieldLabel  matlab.ui.control.Label
        SPRThetaBandwithEditField       matlab.ui.control.EditField
        AlphaBandwithinHzLabel_2        matlab.ui.control.Label
        SPRAlphaBandwithEditField       matlab.ui.control.EditField
        BetaBandwithinHzLabel_2         matlab.ui.control.Label
        SPRBetaBandwithEditField        matlab.ui.control.EditField
        StepsizeinsecondsLabel          matlab.ui.control.Label
        SPRStepSizeEditField            matlab.ui.control.NumericEditField
        OSCsendfrequencyLabel           matlab.ui.control.Label
        donttouchLabel                  matlab.ui.control.Label
        Thisfeaturereturns2globalspectralratiosLabel  matlab.ui.control.Label
        Thisfeatureoutputs2globalspectralratiosLabel  matlab.ui.control.Label
        TopographicDistributionTab      matlab.ui.container.Tab
        TopographicDistributionPanel    matlab.ui.container.Panel
        WindowSizeinsecondsLabel        matlab.ui.control.Label
        TDRequiredSizeEditField         matlab.ui.control.NumericEditField
        FrequencyinHzLabel              matlab.ui.control.Label
        TDFrequencyEditField            matlab.ui.control.NumericEditField
        ChannelsSelectionholdCtrlformulitpleLabel  matlab.ui.control.Label
        TDChannelsListBox               matlab.ui.control.ListBox
        OSCsendfrequencyLabel_2         matlab.ui.control.Label
        default10HzadjusttopatientLabel  matlab.ui.control.Label
        ThisfeatureoutputstherationLabel  matlab.ui.control.Label
        frontpowerbackpowerLabel        matlab.ui.control.Label
        PhaseAmplitudeCouplingTab       matlab.ui.container.Tab
        PhaseAmplitudeCouplingPanel     matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel_3  matlab.ui.control.Label
        PACRequiredSizeEditField        matlab.ui.control.NumericEditField
        ExtraLowFrequencyinHzLabel      matlab.ui.control.Label
        PACExtraLowFrequencyBandwithEditField  matlab.ui.control.EditField
        AlphainHzLabel                  matlab.ui.control.Label
        PACHighFrequencyBandwithEditField  matlab.ui.control.EditField
        OSCsendfrequencyLabel_3         matlab.ui.control.Label
        setto01to1HzLabel               matlab.ui.control.Label
        setto813HzLabel                 matlab.ui.control.Label
        Sends2numbersfrontalparietralLabel  matlab.ui.control.Label
        thefrontalelectrodes2theparietalelectrodesLabel  matlab.ui.control.Label
        SelectChannelsholdCtrlformultiselectionLabel  matlab.ui.control.Label
        PACChannelsListBox              matlab.ui.control.ListBox
        FrontoparietalwPLITab           matlab.ui.container.Tab
        FrontoparietalwPLIPanel         matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel_4  matlab.ui.control.Label
        FPwPLIRequiredSizeEditField     matlab.ui.control.NumericEditField
        SelectChannelsholdCtrlformultiselectionLabel_2  matlab.ui.control.Label
        FPwPLIChannelsListBox           matlab.ui.control.ListBox
        NumberofSurrogatesLabel         matlab.ui.control.Label
        FPwPLINumberSurrogatesEditField  matlab.ui.control.NumericEditField
        pValueLabel                     matlab.ui.control.Label
        FPwPLIPValueEditField           matlab.ui.control.NumericEditField
        FPwPLIPValueCheckBox            matlab.ui.control.CheckBox
        FPwPLIPValueCheckLabel          matlab.ui.control.Label
        OSCsendfrequencyLabel_4         matlab.ui.control.Label
        setto10CPUexpensiveLabel        matlab.ui.control.Label
        typicallysetto05Label           matlab.ui.control.Label
        uncheckiftooCPUheavyLabel       matlab.ui.control.Label
        outputsLabel                    matlab.ui.control.Label
        leftlateralleftmidlinerightlateralrightmidlineLabel  matlab.ui.control.Label
        wPLIanddPLIshopuldhavethesamesettingsLabel_2  matlab.ui.control.Label
        FrontoparietaldPLITab           matlab.ui.container.Tab
        FrontoparietaldPLIPanel         matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel_5  matlab.ui.control.Label
        FPdPLIRequiredSizeEditField     matlab.ui.control.NumericEditField
        SelectChannelsholdCtrlformultiselectionLabel_3  matlab.ui.control.Label
        FPdPLIChannelsListBox           matlab.ui.control.ListBox
        NumberofSurrogatesLabel_2       matlab.ui.control.Label
        FPdPLINumberSurrogatesEditField  matlab.ui.control.NumericEditField
        pValueLabel_2                   matlab.ui.control.Label
        FPdPLIPValueEditField           matlab.ui.control.NumericEditField
        FPdPLIPValueCheckBox            matlab.ui.control.CheckBox
        FPdPLIPValueCheckLabel          matlab.ui.control.Label
        OSCsendfrequencyLabel_5         matlab.ui.control.Label
        setto10CPUexpensiveLabel_2      matlab.ui.control.Label
        typicallysetto05Label_2         matlab.ui.control.Label
        uncheckiftooCPUheavyLabel_2     matlab.ui.control.Label
        outputsLabel_2                  matlab.ui.control.Label
        leftlateralleftmidlinerightlateralrightmidlineLabel_2  matlab.ui.control.Label
        wPLIanddPLIshopuldhavethesamesettingsLabel  matlab.ui.control.Label
        HubLocationTab                  matlab.ui.container.Tab
        HubLocationPanel                matlab.ui.container.Panel
        NumberofSurrogatesLabel_3       matlab.ui.control.Label
        HLNumberSurrogatesEditField     matlab.ui.control.NumericEditField
        WindowSizeinsecondsEditFieldLabel_6  matlab.ui.control.Label
        HLRequiredSizeEditField         matlab.ui.control.NumericEditField
        pValueLabel_3                   matlab.ui.control.Label
        HLPValueEditField               matlab.ui.control.NumericEditField
        TopConnectionThreshold0010Label  matlab.ui.control.Label
        HLTopConnectionThresholdEditField  matlab.ui.control.NumericEditField
        OutputTypeDropDownLabel         matlab.ui.control.Label
        HLOutputTypeDropDown            matlab.ui.control.DropDown
        HLPValueCheckBox                matlab.ui.control.CheckBox
        HLPValueCheckLabel              matlab.ui.control.Label
        HLSendGraphCheckBox             matlab.ui.control.CheckBox
        HLSendGraphCheckLabel           matlab.ui.control.Label
        OSCsendfrequencyLabel_6         matlab.ui.control.Label
        settoo10Label                   matlab.ui.control.Label
        typicallysetto005Label          matlab.ui.control.Label
        typicallysetto005Label_2        matlab.ui.control.Label
        whetherLabel                    matlab.ui.control.Label
        checkifLabel                    matlab.ui.control.Label
        sendslistofconnectionsLabel     matlab.ui.control.Label
        outputtheaveragelocationofthehighestdegreenodeLabel  matlab.ui.control.Label
        PermutationEntropyTab           matlab.ui.container.Tab
        PermutationEntropyPanel         matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel_7  matlab.ui.control.Label
        PERequiredSizeEditField         matlab.ui.control.NumericEditField
        SelectChannelsholdCtrlformultiselectionLabel_4  matlab.ui.control.Label
        PEChannelsListBox               matlab.ui.control.ListBox
        EmbeddingDimensionLabel         matlab.ui.control.Label
        PEEmbeddingDimensionEditField   matlab.ui.control.NumericEditField
        TimeDelayinsecondsLabel         matlab.ui.control.Label
        PETimeDelayEditField            matlab.ui.control.NumericEditField
        OSCsendfrequencyLabel_7         matlab.ui.control.Label
        typicallysetto5Label            matlab.ui.control.Label
        typicallysetto4Label            matlab.ui.control.Label
        ThisfeatureoutputstwonumbersLabel  matlab.ui.control.Label
        oneforthefrontandonefoirthebackLabel  matlab.ui.control.Label
        EEGSonicPanel                   matlab.ui.container.Panel
        LaunchButton                    matlab.ui.control.Button
        FeedbackTextField               matlab.ui.control.TextArea
        SpectralPowerRatioCheckBox      matlab.ui.control.CheckBox
        TopographicDistributionCheckBox  matlab.ui.control.CheckBox
        PhaseAmplitudeCouplingCheckBox  matlab.ui.control.CheckBox
        FrontoparietalwPLICheckBox      matlab.ui.control.CheckBox
        FrontoparietaldPLICheckBox      matlab.ui.control.CheckBox
        HubLocationCheckBox             matlab.ui.control.CheckBox
        PermutationEntropyCheckBox      matlab.ui.control.CheckBox
        DebugModeSwitchLabel            matlab.ui.control.Label
        DebugModeSwitch                 matlab.ui.control.Switch
    end


    properties (Access = private)
        % app_data content
        parameters; % contains editable values
        information; % contains static and state values
        state;
        companion_app; % handler to the companion app
        
        % Colors used by the launch button
        record = [0,1,0];
        replay = [0,0.45,0.74];
        default = [0.5,0.5,0.5];
    end

    properties (Access = public)
        % Used by the headset viewer app to link the two app together
        is_headset_viewer_running;
        is_documentation_viewer_running;
        is_replayer_running;
        is_converter_running;
    end

    methods (Access = private)
    
        % Will save the current state of the GUI into app_data.mat
        function save_gui(app)
            % Save checkbox selection
            app.parameters.spr.is_selected = app.SpectralPowerRatioCheckBox.Value;
            app.parameters.td.is_selected = app.TopographicDistributionCheckBox.Value;
            app.parameters.pac.is_selected = app.PhaseAmplitudeCouplingCheckBox.Value;
            app.parameters.fp_wpli.is_selected = app.FrontoparietalwPLICheckBox.Value;
            app.parameters.fp_dpli.is_selected = app.FrontoparietaldPLICheckBox.Value;
            app.parameters.hl.is_selected = app.HubLocationCheckBox.Value;
            app.parameters.pe.is_selected = app.PermutationEntropyCheckBox.Value;
            
            % Save General Panel
            app.information.saving_directory = app.SavingDirectoryLabel.Text;
            app.information.folder_name = app.FolderNameEditField.Value;
            app.information.is_save_features = 1;
            app.parameters.general.replay.replay_directory = app.ReplayDirectoryLabel.Text;
           
            % EEG selection saved at the end
            
            % Save Home Panel
            app.information.debug_mode = is_debug(app);
            app.state.is_debug = is_debug(app);
            
            % Save OSC Panel
            path = {};
            ip = {};
            port = {};
            items = app.OSCReceiversListBox.Items;
            % Process each items and save them in path,ip and port variables
            for i=1:length(items)
                raw_data = strsplit(items{1,i},',');
                path = [path,raw_data{1,1}];
                ip = [ip,raw_data{1,2}];
                port = [port,raw_data{1,3}];
            end
            app.parameters.osc.path = path;
            app.parameters.osc.ip = ip;
            app.parameters.osc.port = port;
            
            % Save SPR Panel
            app.parameters.spr.window_size = app.SPRWindowSizeEditField.Value;
            app.parameters.spr.step_size = app.SPRStepSizeEditField.Value;
            app.parameters.spr.time_bandwith_product = app.SPRTimeBandwithProductEditField.Value;
            app.parameters.spr.number_tapers = app.SPRNumberTapersEditField.Value;
            
            app.parameters.spr.theta.bandwith = process_bandwidth(app.SPRThetaBandwithEditField.Value);
            
            
            app.parameters.spr.alpha.bandwith = process_bandwidth(app.SPRAlphaBandwithEditField.Value);
            
            
            app.parameters.spr.beta.bandwith = process_bandwidth(app.SPRBetaBandwithEditField.Value);
            
            
            app.parameters.spr.required_size = app.SPRRequiredSizeEditField.Value;
            
            % Save TD Panel
            app.parameters.td.required_size = app.TDRequiredSizeEditField.Value;
            app.parameters.td.frequency = app.TDFrequencyEditField.Value;
   
            
            % Save PAC Panel
            app.parameters.pac.required_size = app.PACRequiredSizeEditField.Value;
            app.parameters.pac.extra_low_frequency.bandwith = process_bandwidth(app.PACExtraLowFrequencyBandwithEditField.Value);
            app.parameters.pac.high_frequency.bandwith = process_bandwidth(app.PACHighFrequencyBandwithEditField.Value);
        
            %Save FP_WPLI Panel
            app.parameters.fp_wpli.required_size = app.FPwPLIRequiredSizeEditField.Value;
            app.parameters.fp_wpli.number_surrogates = app.FPwPLINumberSurrogatesEditField.Value;
            app.parameters.fp_wpli.p_value = app.FPwPLIPValueEditField.Value;
            app.parameters.fp_wpli.is_surrogates = app.FPwPLIPValueCheckBox.Value;
            
            %Save FP_DPLI Panel
            app.parameters.fp_dpli.required_size = app.FPdPLIRequiredSizeEditField.Value;           
            app.parameters.fp_dpli.number_surrogates = app.FPdPLINumberSurrogatesEditField.Value;
            app.parameters.fp_dpli.p_value = app.FPdPLIPValueEditField.Value;
            app.parameters.fp_dpli.is_surrogates = app.FPdPLIPValueCheckBox.Value;
                    
            % Save HL Panel
            app.parameters.hl.required_size = app.HLRequiredSizeEditField.Value;
            app.parameters.hl.number_surrogates = app.HLNumberSurrogatesEditField.Value;
            app.parameters.hl.p_value = app.HLPValueEditField.Value;
            app.parameters.hl.top_connection_threshold = app.HLTopConnectionThresholdEditField.Value;
            app.parameters.hl.output_type = app.HLOutputTypeDropDown.Value;
            app.parameters.hl.is_surrogates = app.HLPValueCheckBox.Value;
            app.parameters.hl.is_graph = app.HLSendGraphCheckBox.Value;
            
            % Save PE Panel
            app.parameters.pe.required_size = app.PERequiredSizeEditField.Value;
            app.parameters.pe.embedding_dimension = app.PEEmbeddingDimensionEditField.Value;
            app.parameters.pe.time_delay = app.PETimeDelayEditField.Value;
            
            % ALL channels selection Boxes
            save_channels_selection(app);
            
            % EEG selection Panel
            app.state.device.is_egi129 = app.EGI129Button.Value;
            app.state.device.is_dsi24 = 0;
            app.state.device.is_replay = app.ReplayButton.Value;

            if(app.state.device.is_replay == 0)
                app.parameters.general.egi129.is_selected = app.EGI129Button.Value;
                app.parameters.general.egi129.data_acquisition_size = app.EGI129DataSizeEditField.Value;
                app.parameters.general.egi129.ip = app.EGI129IPEditField.Value;
                app.parameters.general.egi129.sampling_rate = app.EGI129SamplingRateEditField.Value;
                app.parameters.general.dsi24.is_selected = 0;
                app.parameters.general.replay.is_selected = app.ReplayButton.Value;
            end
            
            
            %Write to app_data
            parameters = app.parameters;
            information = app.information;
            state = app.state;
            save(strcat(app.information.base_directory,filesep,'data',filesep,'app_data'),'parameters','-append');
            save(strcat(app.information.base_directory,filesep,'data',filesep,'app_data'),'information','-append');
            save(strcat(app.information.base_directory,filesep,'data',filesep,'app_data'),'state','-append');            
        end
    
        function update_gui(app)
            % Populate Channels Selection
            populate_channels_selection(app);
            
            % Update Checkboxes
            app.SpectralPowerRatioCheckBox.Value = app.parameters.spr.is_selected;
            app.TopographicDistributionCheckBox.Value = app.parameters.td.is_selected;
            app.PhaseAmplitudeCouplingCheckBox.Value = app.parameters.pac.is_selected;
            app.FrontoparietalwPLICheckBox.Value = app.parameters.fp_wpli.is_selected;
            app.FrontoparietaldPLICheckBox.Value = app.parameters.fp_dpli.is_selected;
            app.HubLocationCheckBox.Value = app.parameters.hl.is_selected;
            app.PermutationEntropyCheckBox.Value = app.parameters.pe.is_selected;     
            
            % Update General
            % Introducing State Variables
            app.EGI129Button.Value = app.state.device.is_egi129;
            app.ReplayButton.Value = app.state.device.is_replay;
            
            app.SavingDirectoryLabel.Text = app.information.saving_directory;
            app.FolderNameEditField.Value = app.information.folder_name;

            app.EGI129DataSizeEditField.Value = app.parameters.general.egi129.data_acquisition_size;
            app.EGI129IPEditField.Value = app.parameters.general.egi129.ip;
            app.EGI129SamplingRateEditField.Value = app.parameters.general.egi129.sampling_rate;

            % Update Home
            app.DebugModeSwitch.Value = get_debug_mode(app.information.debug_mode);
            
            % Introducing State Variables
            app.DebugModeSwitch.Value = get_debug_mode(app.state.is_debug);
            
            % Update OSC Receivers
            app.OSCReceiversListBox.Items = {};
            % We recreate the strings from path,ip and port
            for i = 1:length(app.parameters.osc.path)
                osc_receiver = strcat(app.parameters.osc.path{1,i},",",app.parameters.osc.ip{1,i},",",app.parameters.osc.port{1,i});
                app.OSCReceiversListBox.Items = [app.OSCReceiversListBox.Items,osc_receiver];
            end

            % Update SPR Panel
            app.SPRWindowSizeEditField.Value = app.parameters.spr.window_size;
            app.SPRStepSizeEditField.Value = app.parameters.spr.step_size;
            app.SPRTimeBandwithProductEditField.Value = app.parameters.spr.time_bandwith_product;
            app.SPRNumberTapersEditField.Value = app.parameters.spr.number_tapers;
            app.SPRThetaBandwithEditField.Value = mat2str(app.parameters.spr.theta.bandwith);
            app.SPRAlphaBandwithEditField.Value = mat2str(app.parameters.spr.alpha.bandwith);
            app.SPRBetaBandwithEditField.Value = mat2str(app.parameters.spr.beta.bandwith);           
            app.SPRRequiredSizeEditField.Value = app.parameters.spr.required_size;
            
            % Update TD Panel
            app.TDRequiredSizeEditField.Value = app.parameters.td.required_size;
            app.TDFrequencyEditField.Value = app.parameters.td.frequency; 
            
            % Update PAC Panel
            app.PACRequiredSizeEditField.Value = app.parameters.pac.required_size;            
            app.PACExtraLowFrequencyBandwithEditField.Value = mat2str(app.parameters.pac.extra_low_frequency.bandwith);
            app.PACHighFrequencyBandwithEditField.Value = mat2str(app.parameters.pac.high_frequency.bandwith);
            
            
            % Update FP_WPLI Panel
            app.FPwPLIRequiredSizeEditField.Value= app.parameters.fp_wpli.required_size;
            app.FPwPLINumberSurrogatesEditField.Value = app.parameters.fp_wpli.number_surrogates;
            app.FPwPLIPValueEditField.Value = app.parameters.fp_wpli.p_value;
            app.FPwPLIPValueCheckBox.Value = app.parameters.fp_wpli.is_surrogates;
            
            % Update FP_DPLI Panel
            app.FPdPLIRequiredSizeEditField.Value = app.parameters.fp_dpli.required_size;
            app.FPdPLINumberSurrogatesEditField.Value = app.parameters.fp_dpli.number_surrogates;
            app.FPdPLIPValueEditField.Value = app.parameters.fp_dpli.p_value;
            app.FPdPLIPValueCheckBox.Value = app.parameters.fp_dpli.is_surrogates; 
            
            % Update Hub Location
            if(app.parameters.general.egi129.is_selected)
                app.HubLocationCheckBox.Enable = 1;
            elseif(app.parameters.general.dsi24.is_selected)
                app.HubLocationCheckBox.Value = 0;
                app.HubLocationCheckBox.Enable = 0;
            end
            app.HLRequiredSizeEditField.Value = app.parameters.hl.required_size;
            app.HLNumberSurrogatesEditField.Value = app.parameters.hl.number_surrogates;
            app.HLPValueEditField.Value = app.parameters.hl.p_value;
            app.HLTopConnectionThresholdEditField.Value = app.parameters.hl.top_connection_threshold;
            app.HLOutputTypeDropDown.Value = app.parameters.hl.output_type;
            app.HLPValueCheckBox.Value = app.parameters.hl.is_surrogates;
            app.HLSendGraphCheckBox.Value = app.parameters.hl.is_graph;
            
            % Update Permutation Entropy
            app.PERequiredSizeEditField.Value = app.parameters.pe.required_size;
            app.PEEmbeddingDimensionEditField.Value = app.parameters.pe.embedding_dimension;
            app.PETimeDelayEditField.Value = app.parameters.pe.time_delay;
            
            % Populate channels selection
            update_channels_selection(app)
            
            % Launch button aesthethics
            if(app.ReplayButton.Value == 1)
                app.LaunchButton.BackgroundColor = app.replay;
            else
                app.LaunchButton.BackgroundColor = app.default;
            end
        end
    
        % Populate all the channels choose box with 
        % the channels of the current selected headset
        function populate_channels_selection(app)
            
            %TD
            app.TDChannelsListBox.Value = {};
            app.TDChannelsListBox.Items = {};
            app.TDChannelsListBox.Value = {};
            
            % PAC 
            app.PACChannelsListBox.Value = {};
            app.PACChannelsListBox.Items = {};
            app.PACChannelsListBox.ItemsData = {};
            
            % FP_WPLI
            app.FPwPLIChannelsListBox.Value = {};
            app.FPwPLIChannelsListBox.Items = {};
            app.FPwPLIChannelsListBox.ItemsData = {};   
            
            % FP_DPLI
            app.FPdPLIChannelsListBox.Value = {};
            app.FPdPLIChannelsListBox.Items = {};
            app.FPdPLIChannelsListBox.ItemsData = {}; 
            
            % PE
            app.PEChannelsListBox.Value = {};
            app.PEChannelsListBox.Items = {};
            app.PEChannelsListBox.ItemsData = {};
            
            % Select the right channels
            channels_selection = {};            
            if(app.parameters.general.egi129.is_selected == 1)
                channels_selection = {app.information.headset.egi129.channels_location.labels};
            elseif(app.parameters.general.dsi24.is_selected == 1)
                channels_selection = {app.information.headset.dsi24.channels_location.labels};                
            end
            
            % Populate all the channels panels
            app.TDChannelsListBox.Items = channels_selection;
            
            app.PACChannelsListBox.Items = channels_selection;
            
            app.FPwPLIChannelsListBox.Items = channels_selection;
            
            app.FPdPLIChannelsListBox.Items = channels_selection;
            
            app.PEChannelsListBox.Items = channels_selection;  
            
        end
        
        % Untick a specific analysis techniques
        function tag_reason = untick(app,tag)
            switch tag
                case 'spr'
                    tag_reason = "Spectral Power Ratio inputs are incorect. ";
                    app.SpectralPowerRatioCheckBox.Value = 0;
                case 'td'
                    tag_reason = "Topographic Distribution inputs are incorect. ";                    
                    app.TopographicDistributionCheckBox.Value = 0;
                case 'pac'
                    tag_reason = "Phase Amplitude Coupling inputs are incorect. ";                   
                    app.PhaseAmplitudeCouplingCheckBox.Value = 0;
                case 'fp_wpli'
                    tag_reason = "Frontoparietal wPLI inputs are incorect. ";                    
                    app.FrontoparietalwPLICheckBox.Value = 0;
                case 'fp_dpli'
                    tag_reason = "Frontoparietal dPLI inputs are incorect. ";                    
                    app.FrontoparietaldPLICheckBox.Value = 0;
                case 'hl'
                    tag_reason = "Hub location inputs are incorect. ";                 
                    app.HubLocationCheckBox.Value = 0;
                case 'pe'
                    tag_reason = "Permutation Entropy inputs are incorect. ";                    
                    app.PermutationEntropyCheckBox.Value = 0;
            end
        end
        
        % Write a message to the feedback box
        function write_to_feedback(app,message)
            app.FeedbackTextField.Value = message;
        end
        
        % Here we disable or enable everything that will cause trouble
        % while EEGsonic is running or stopped        
        function switch_state_gui(app,new_state)
            
            % Headset selection
            app.EGI129Button.Enable = new_state;
            app.ReplayButton.Enable = new_state;
            
            % OSC Receivers List
            app.RemoveOSCReceiversButton.Enable = new_state;
            app.AddOSCReceiversButton.Enable = new_state;
            
        end
                
        % This function checks if there is enough time to add a given analysis technique
        function [is_enough,time_left] = check_if_enough_time(app)
            write_to_feedback(app,"Calculating if enough time...");
            switch_all_checkbox(app,0); % disable all checkboxes
            pause(1); % this is needed for the GUI change to appear
            save_gui(app); % save the state for the function is_enough_time
            [is_enough,time_left] = is_enough_time(app.information,app.parameters);
            switch_all_checkbox(app,1); % enable all checkboxes
        end
        
        % Make the checkboxes enabled or disabled
        function switch_all_checkbox(app,is_enable)
            app.SpectralPowerRatioCheckBox.Enable = is_enable;
            app.TopographicDistributionCheckBox.Enable = is_enable;
            app.PhaseAmplitudeCouplingCheckBox.Enable = is_enable;
            app.FrontoparietalwPLICheckBox.Enable = is_enable;
            app.FrontoparietaldPLICheckBox.Enable = is_enable;
            app.HubLocationCheckBox.Enable = is_enable;
            app.PermutationEntropyCheckBox.Enable = is_enable;
        end
        
        % Save all the channels selection in one go
        function save_channels_selection(app)
            % Save the channels selection at the right place in the structure
            if(app.parameters.general.egi129.is_selected)
                % TD
                app.parameters.td.channels = app.TDChannelsListBox.Value;
                
                % PAC
                app.parameters.pac.channels = app.PACChannelsListBox.Value;
                
                % FP_wPLI
                app.parameters.fp_wpli.channels = app.FPwPLIChannelsListBox.Value;
                
                % FP_dPLI
                app.parameters.fp_dpli.channels = app.FPdPLIChannelsListBox.Value;
                
                % PE
                app.parameters.pe.channels = app.PEChannelsListBox.Value;
            end     
               
        end
        
        % Update all the channels selection in one go
        function update_channels_selection(app)
            % This populate the channels selection with the currently selected EEG headset
            if(app.parameters.general.egi129.is_selected)
                % TD
                app.TDChannelsListBox.Value = app.parameters.td.channels;
                
                % PAC
                app.PACChannelsListBox.Value = app.parameters.pac.channels;
                
                % FP_wPLI
                app.FPwPLIChannelsListBox.Value = app.parameters.fp_wpli.channels;
                
                % FP_dPLI
                app.FPdPLIChannelsListBox.Value = app.parameters.fp_dpli.channels;
                
                % PE
                app.PEChannelsListBox.Value = app.parameters.pe.channels;
            end 
           
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clear Screen
            clc;
            
            % Setup
            disp('Setup...');
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
            
            % Setting up Variables
            [app.information,app.parameters,app.state] = load_app_data(strcat(base_directory,filesep,'data',filesep,'app_data.mat'),1);
            app.information.base_directory = base_directory;
            app.information.replay_path = strcat(base_directory,filesep,'data',filesep,'replay_data.mat');
            app.is_headset_viewer_running = 0;
            app.is_documentation_viewer_running = 0;
            app.is_replayer_running = 0;
            app.is_converter_running = 0;
            
            app.companion_app = struct();
            app.companion_app.headset_viewer = [];
            app.companion_app.documentation_viewer = [];
            app.companion_app.replayer = [];
            
            % Populating the GUI with saved data
            disp('Setting the GUI...');
            update_gui(app);
            
            % Clear the screen again
            clc;
            
            % Welcoming the user
            write_to_feedback(app,app.information.start_message);
            disp(app.information.start_message);
            
        end

        % Menu selected function: QuitEEGsonicMenu
        function QuitEEGsonicMenuSelected(app, event)
            disp(app.information.exit_message);
            save_gui(app);
            app.delete();
        end

        % Menu selected function: SaveDefaultSettingsMenu
        function SaveDefaultSettingsMenuSelected(app, event)
            write_to_feedback(app,'Saving Settings...');
            save_gui(app);
            write_to_feedback(app,'Finished Saving!');
        end

        % Button pushed function: SelectSavingDirectoryButton
        function SelectSavingDirectoryButtonPushed(app, event)
            path = uigetdir;
            if(path ~= 0)
               app.information.saving_directory = path;
               app.SavingDirectoryLabel.Text = path;
               write_to_feedback(app,['Updated saving directory to : ',path]);
            end            
        end

        % Selection changed function: EEGdeviceButtonGroup
        function EEGdeviceButtonGroupSelectionChanged(app, event)
               save_gui(app);
               update_gui(app);            
            if(app.ReplayButton.Value)
               app.LaunchButton.BackgroundColor = app.replay;
            else
                % Reload the base information
                base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
               [app.information,~,app.state] = load_app_data(strcat(app.information.base_directory,filesep,'data',filesep,'backup_app_data.mat'),1);
               app.information.base_directory = base_directory;
               app.LaunchButton.BackgroundColor = app.default;
               save_gui(app);
               update_gui(app); 
            end
        end

        % Close request function: EEGSonicUIFigure
        function EEGSonicUIFigureCloseRequest(app, event)
            save_gui(app);
            if(app.is_headset_viewer_running)
               delete(app.companion_app.headset_viewer); 
            end
            if(app.is_documentation_viewer_running)
                delete(app.companion_app.documentation_viewer);
            end
            if(app.is_replayer_running)
               delete(app.companion_app.replayer);
            end
            if(app.is_converter_running)
               delete(app.companion_app.converter); 
            end
            delete(app);
            
        end

        % Value changed function: SameMachineCheckBox
        function SameMachineCheckBoxValueChanged(app, event)
            same_machine_value = app.SameMachineCheckBox.Value;
            if(same_machine_value == 1)
               app.SelectOSCReceiversButton.Enable = 1;  
               app.PathEditField.Value = "";
               
           else
               app.SelectOSCReceiversButton.Enable = 0;
               app.PathEditField.Value = "external";
            end
        end

        % Button pushed function: SelectOSCReceiversButton
        function SelectOSCReceiversButtonPushed(app, event)
            % When this Button is push we need to let the user select the path
            % to a OSC receiver.
            [filename, pathname] = uigetfile('*', 'Select an OSC Receiver');
            full_path = [pathname,filename];
            if(filename ~=0)
               app.PathEditField.Value = full_path; 
            end
        end

        % Button pushed function: AddOSCReceiversButton
        function AddOSCReceiversButtonPushed(app, event)
            [is_valid,reason] = is_input_valid(app,'osc');
            if(is_valid)
                new_osc_receiver = strcat(app.PathEditField.Value,",",app.IPAddressEditField.Value,",",app.PortEditField.Value);
                app.OSCReceiversListBox.Items = [app.OSCReceiversListBox.Items,new_osc_receiver];
                write_to_feedback(app,"Added new OSC receiver!");
            else
                write_to_feedback(app,"Error! OSC receiver not added." + reason);
            end
        end

        % Button pushed function: RemoveOSCReceiversButton
        function RemoveOSCReceiversButtonPushed(app, event)
            selected_item = app.OSCReceiversListBox.Value;
            items = app.OSCReceiversListBox.Items;
            new_items = {};
            if(~isempty(app.OSCReceiversListBox.Value))
                app.OSCReceiversListBox.Value = {};
                app.OSCReceiversListBox.Items = {};
                for i = 1:length(items)
                    if(~strcmp(selected_item,items(1,i)))
                        app.OSCReceiversListBox.Items = [app.OSCReceiversListBox.Items,items(1,i)];
                    end
                end
            end
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            % Save the current State of the GUI
            save_gui(app);
            
            %% Check if we need to do replay or not
            if(app.LaunchButton.BackgroundColor == app.replay)
                % Check if the replay directory is valid
                loading_directory = app.information.replay_directory;                
                if(check_replay_directory(loading_directory) == 0)
                    write_to_feedback(app,"The replay folder you selected is not valid (General Tab).");
                    return;
                end
                
                % at this point we assume that everything is valid
                % Pre-Launch Setup
                app.LaunchButton.Enable = 0;
                pause(2);
                                
                % Launch the Replayer app
                if(app.is_replayer_running == 0)
                    app.companion_app.replayer = replayer_app();
                    app.companion_app.replayer.main_application = app;
                    app.is_replayer_running = 1;
                end
                
                % Setup saving directory
                app.information.data_directory = strcat(app.information.replay_directory,filesep,'data');
                app.information.features_directory = strcat(app.information.replay_directory,filesep,'features');
                
                % Launch Scheduler
                [features_worker] = launch_replayer(app.information,app.parameters);
                app.information.features_worker = features_worker;
                
                % EEGSonic is Launched at this point
                app.LaunchButton.Enable = 1;
                app.LaunchButton.BackgroundColor = app.record;
                app.LaunchButton.Text = "Replay";
                write_to_feedback(app,"EEGsonic started replaying. ");
                switch_state_gui(app,0); % disable stuff that will cause trouble

            elseif(app.LaunchButton.BackgroundColor == app.record) % STOP RECORDING
                %% Delete the replayer app if it is running
                if(app.is_replayer_running == 1)
                    % Delete the application
                    delete(app.companion_app.replayer);
                    app.companion_app.replayer = [];
                    app.is_replayer_running = 0;                    
                end
                
                %% Close the workers
                if(app.ReplayButton.Value ~= 1)
                    cancel(app.information.data_worker);
                end
                cancel(app.information.features_worker);
                app.information.data_worker = [];
                app.information.features_worker = [];
                
                launch_cleaner(app.information,app.parameters);
                if(app.ReplayButton.Value ~= 1)
                    app.LaunchButton.BackgroundColor = app.default;
                else
                    app.LaunchButton.BackgroundColor = app.replay;
                end
                app.LaunchButton.Text = "Launch";
                write_to_feedback(app,"EEGsonic is stopped.");
                switch_state_gui(app,1);
                
            else % START RECORDING
                analysis_tag = {'spr','td','pac','fp_wpli','fp_dpli','hl','pe'};
                is_valid = 1;
                reason = "";
                
                % Check that every analysis technique are valid
                for i = 1:length(analysis_tag)
                   tag = analysis_tag{i};
                   [is_tag_valid,~] = is_input_valid(app,tag);
                   if(i == 1)
                   end
                   if(is_tag_valid == 0 && is_ticked(app,tag))
                      [tag_reason] = untick(app,tag);
                      reason = reason + tag_reason;
                      is_valid = 0;
                   end
                end
                
                % Save or abort launching the app
                if(is_valid)
                    save_gui(app); % Need to save before going into the workers
                else
                   write_to_feedback(app,reason + "Please review them before launching EEGsonic. ");
                   return;
                end
                
                % Check if the saving directory exist in the system
                if(isfolder(app.information.saving_directory) == 0)
                    write_to_feedback(app,"The folder you selected doesn't exist in this Computer (General Tab).");
                    return;
                end
                
                % Start parallele pool of worker
                write_to_feedback(app,"Waking up the cores...");
                try
                    disp('Starting Parallele workers (may take either 1 min or 1 sec) ...');
                    parpool;
                catch
                    % Nothing to do in this case
                end

                % Pre-Launch Setup
                app.LaunchButton.Enable = 0;
                app.LaunchButton.BackgroundColor = [0.93,0.69,0.13];
                app.LaunchButton.Text = "Warming up";
                write_to_feedback(app,"EEGsonic is warming up. ");
                pause(2);
                
                %Launch Scheduler
                [data_worker,features_worker] = launch_scheduler(app.information,app.parameters,app.state);
                app.information.data_worker = data_worker;
                app.information.features_worker = features_worker;
                
                % EEGSonic is Launched at this point
                app.LaunchButton.Enable = 1;
                app.LaunchButton.BackgroundColor = app.record;
                app.LaunchButton.Text = "Recording";
                write_to_feedback(app,"EEGsonic started recording. ");
                
                switch_state_gui(app,0); % disable stuff that will cause trouble
            end
            
        end

        % Value changed function: SpectralPowerRatioCheckBox
        function SpectralPowerRatioCheckBoxValueChanged(app, event)
            value = app.SpectralPowerRatioCheckBox.Value;
            if(value == 1)
               [is_valid,reason] = is_input_valid(app,'spr');
               if(is_valid == 1)

                   [is_enough_time,time_left] = check_if_enough_time(app);
                   if(is_enough_time)
                       write_to_feedback(app,"Spectral Power Ratio inputs seems valid! Time Left: " + num2str(time_left));
                   else
                       write_to_feedback(app,"Not enough time left to do Spectral power Ratio. Try it again (it gets faster if you call it multiple time).");
                       app.SpectralPowerRatioCheckBox.Value = 0;
                   end
               else
                   write_to_feedback(app,"Error! Spectral Power Ratio inputs not valid." + reason);
                   app.SpectralPowerRatioCheckBox.Value = 0;
               end
            else
                write_to_feedback(app,"Spectral Power Ratio was unchecked.");
            end
            save_gui(app);
        end

        % Value changed function: TopographicDistributionCheckBox
        function TopographicDistributionCheckBoxValueChanged(app, event)
            value = app.TopographicDistributionCheckBox.Value;
            if(value == 1)
               [is_valid,reason] = is_input_valid(app,'td');               
               if(is_valid == 1)
                   [is_enough_time,time_left] = check_if_enough_time(app);
                   if(is_enough_time)
                       write_to_feedback(app,"Topographic Distribution inputs seems valid! Time Left: " + num2str(time_left));
                   else
                       write_to_feedback(app,"Not enough time left to do Topographic Distribution. Try it again (it gets faster if you call it multiple time).");
                       app.TopographicDistributionCheckBox.Value = 0;
                   end
               else
                   write_to_feedback(app,"Error! Topographic Distribution inputs not valid." + reason);
                   app.TopographicDistributionCheckBox.Value = 0;
               end
            else
                write_to_feedback(app,"Topographic Distribution was unchecked.");
            end
            save_gui(app);            
        end

        % Value changed function: PhaseAmplitudeCouplingCheckBox
        function PhaseAmplitudeCouplingCheckBoxValueChanged(app, event)
            value = app.PhaseAmplitudeCouplingCheckBox.Value;
            if(value == 1)
               [is_valid,reason] = is_input_valid(app,'pac');
               if(is_valid == 1)
                   [is_enough_time,time_left] = check_if_enough_time(app);
                   if(is_enough_time)
                       write_to_feedback(app,"Phase Amplitude Coupling inputs seems valid! Time Left: " + num2str(time_left));
                   else
                       write_to_feedback(app,"Not enough time left to do Phase Amplitude Coupling.  Try it again (it gets faster if you call it multiple time).");
                       app.PhaseAmplitudeCouplingCheckBox.Value = 0;
                   end
               else
                   write_to_feedback(app,"Error! Phase Amplitude Coupling inputs not valid." + reason);
                   app.PhaseAmplitudeCouplingCheckBox.Value = 0;
               end
            else
                write_to_feedback(app,"Phase Amplitude Coupling was unchecked.");
            end    
            save_gui(app);            
        end

        % Value changed function: FrontoparietalwPLICheckBox
        function FrontoparietalwPLICheckBoxValueChanged(app, event)
            value = app.FrontoparietalwPLICheckBox.Value;
            if(value == 1)
               [is_valid,reason] = is_input_valid(app,'fp_wpli');
               if(is_valid == 1)
                   [is_enough_time,time_left] = check_if_enough_time(app);
                   if(is_enough_time)
                       write_to_feedback(app,"Frontoparietal wPLI inputs seems valid! Time Left: " + num2str(time_left));
                   else
                       write_to_feedback(app,"Not enough time left to do Frontoparietal wPLI.  Try it again (it gets faster if you call it multiple time).");
                       app.FrontoparietalwPLICheckBox.Value = 0;
                   end
               else
                   write_to_feedback(app,"Error! Frontoparietal wPLI inputs not valid." + reason);
                   app.FrontoparietalwPLICheckBox.Value = 0;
               end
            else
                write_to_feedback(app,"Frontoparietal wPLI was unchecked.");
            end 
            save_gui(app);            
        end

        % Value changed function: FrontoparietaldPLICheckBox
        function FrontoparietaldPLICheckBoxValueChanged(app, event)
            value = app.FrontoparietaldPLICheckBox.Value;
            if(value == 1)
               [is_valid,reason] = is_input_valid(app,'fp_dpli');
               if(is_valid == 1)
                   [is_enough_time,time_left] = check_if_enough_time(app);
                   if(is_enough_time)
                       write_to_feedback(app,"Frontoparietal dPLI inputs seems valid! Time Left: " + num2str(time_left));
                   else
                       write_to_feedback(app,"Not enough time left to do Frontoparietal dPLI.  Try it again (it gets faster if you call it multiple time).");
                       app.FrontoparietaldPLICheckBox.Value = 0;
                   end
               else
                   write_to_feedback(app,"Error! Frontoparietal dPLI inputs not valid." + reason);
                   app.FrontoparietaldPLICheckBox.Value = 0;
               end
            else
                write_to_feedback(app,"Frontoparietal dPLI was unchecked.");
            end   
            save_gui(app);            
        end

        % Value changed function: HubLocationCheckBox
        function HubLocationCheckBoxValueChanged(app, event)
            value = app.HubLocationCheckBox.Value;
            if(value == 1)
               [is_valid,reason] = is_input_valid(app,'hl');
               if(is_valid == 1)
                   [is_enough_time,time_left] = check_if_enough_time(app);
                   if(is_enough_time)
                       write_to_feedback(app,"Hub Location inputs seems valid! Time Left: " + num2str(time_left));
                   else
                       write_to_feedback(app,"Not enough time left to do Hub Location.  Try it again (it gets faster if you call it multiple time).");
                       app.HubLocationCheckBox.Value = 0;
                   end
               else
                   write_to_feedback(app,"Error! Hub Location inputs not valid." + reason);
                   app.HubLocationCheckBox.Value = 0;
               end
            else
                write_to_feedback(app,"Hub Location was unchecked.");
            end  
            save_gui(app);            
        end

        % Value changed function: PermutationEntropyCheckBox
        function PermutationEntropyCheckBoxValueChanged(app, event)
            value = app.PermutationEntropyCheckBox.Value;
            if(value == 1)
               [is_valid,reason] = is_input_valid(app,'pe');
               if(is_valid == 1)
                   [is_enough_time,time_left] = check_if_enough_time(app);
                   if(is_enough_time)
                       write_to_feedback(app,"Permutation Entropy inputs seems valid! Time Left: " + num2str(time_left));
                   else
                       write_to_feedback(app,"Not enough time left to do Permutation Entropy.  Try it again (it gets faster if you call it multiple time).");
                       app.FrontoparietaldPLICheckBox.Value = 0;
                   end                   
               else
                   write_to_feedback(app,"Error! Permutation Entropy inputs not valid." + reason);
                   app.PermutationEntropyCheckBox.Value = 0;
               end
            else
                write_to_feedback(app,"Permutation Entropy was unchecked.");
            end  
            save_gui(app);            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selected_tab = app.TabGroup.SelectedTab.Title;
            if(app.is_documentation_viewer_running)
               app.companion_app.documentation_viewer.select_tab(selected_tab);
            end
        end

        % Button pushed function: SelectReplayDirectoryButton
        function SelectReplayDirectoryButtonPushed(app, event)
            path = uigetdir;
            if(path ~= 0 & check_replay_directory(path))
               app.information.replay_directory = path;
               app.ReplayDirectoryLabel.Text = path;
               full_path = strcat(path,filesep,'parameters',filesep,'app_data.mat');
               
               [information,app.parameters,~] = load_app_data(full_path,0);
               app.information.headset.egi129 = information.headset.egi129;
               write_to_feedback(app,['Updated replay directory to : ',path,' and loaded the parameters (you can edit them)']);
               update_gui(app);
            elseif(path ~= 0)
               write_to_feedback(app,['Replay directory is not valid : ',path]);
            end 
        end

        % Menu selected function: LoadPresetMenu
        function LoadPresetMenuSelected(app, event)
            [file,path] = uigetfile;
            if(path ~= 0)
                try
                    full_path = strcat(path,filesep,file);
                    [app.information,app.parameters] = load_app_data(full_path,0);
                    update_gui(app);
                    write_to_feedback(app,['Loaded the parameters from : ',path]);
                catch
                    write_to_feedback(app,['Cannot load this app_data preset.']);
                end
            end
        end

        % Menu selected function: SavePresetMenu
        function SavePresetMenuSelected(app, event)
            save_gui(app);
            [file,path] = uiputfile('.mat');
            if(path ~= 0)
                try
                    full_path = strcat(path,filesep,file);
                    save_app_data(app.information,app.parameters,full_path);
                    write_to_feedback(app,['Saved the in: ',path]);
                catch
                    write_to_feedback(app,['Cannot save the preset.']);
                end
            end            
        end

        % Callback function
        function ConvertEEGdataMenu2Selected(app, event)
            % Launch Headset Viewer
            if(app.is_converter_running == 0)
                app.companion_app.converter = converter_app();
                app.companion_app.converter.main_application = app;
                app.is_headset_viewer_running = 1;
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
           % Launch Headset Viewer
            if(app.is_documentation_viewer_running == 0)
                app.companion_app.documentation_viewer = documentation_viewer_app();
                app.companion_app.documentation_viewer.main_application = app;
                app.is_documentation_viewer_running = 1;
                selected_tab = app.TabGroup.SelectedTab.Title;
                app.companion_app.documentation_viewer.select_tab(selected_tab);
            else
                % Delete the application
                delete(app.companion_app.documentation_viewer);
                app.companion_app.documentation_viewer = [];
                app.is_documentation_viewer_running = 0;
            end            
        end

        % Callback function
        function EEGHeadsetsMenu2Selected(app, event)
           % Launch Headset Viewer
            if(app.is_headset_viewer_running == 0)
                app.companion_app.headset_viewer = headset_viewer_app();
                app.companion_app.headset_viewer.main_application = app;
                app.is_headset_viewer_running = 1;
            else
                % Deleting the figures before exiting the app
                delete(app.companion_app.headset_viewer.figure_egi129);
                delete(app.companion_app.headset_viewer.figure_dsi24);
                % Delete the application
                delete(app.companion_app.headset_viewer);
                app.companion_app.headset_viewer = [];
                app.is_headset_viewer_running = 0;
            end            
        end

        % Menu selected function: BIAPTMenu
        function BIAPTMenuSelected(app, event)
            if(ismac)
                system('open https://github.com/BIAPT/EEGsonic');
            else
                system('start https://github.com/BIAPT/EEGsonic');
            end            
        end

        % Size changed function: GeneralTab
        function GeneralTabSizeChanged(app, event)
            position = app.GeneralTab.Position;
            
        end

        % Menu selected function: ConvertEEGDataMenu
        function ConvertEEGDataMenuSelected(app, event)
             % Launch Converter
            if(app.is_converter_running == 0)
                app.companion_app.converter = converter_app();
                app.companion_app.converter.main_application = app;
                app.is_converter_running = 1;
            end
        end

        % Menu selected function: ViewEEGHeadsetMenu
        function ViewEEGHeadsetMenuSelected(app, event)
             % Launch Headset Viewer
            if(app.is_headset_viewer_running == 0)
                app.companion_app.headset_viewer = headset_viewer_app();
                app.companion_app.headset_viewer.main_application = app;
                app.is_headset_viewer_running = 1;
            else
                % Deleting the figures before exiting the app
                delete(app.companion_app.headset_viewer.figure_egi129);
                delete(app.companion_app.headset_viewer.figure_dsi24);
                % Delete the application
                delete(app.companion_app.headset_viewer);
                app.companion_app.headset_viewer = [];
                app.is_headset_viewer_running = 0;
            end      
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGSonicUIFigure = uifigure;
            app.EEGSonicUIFigure.Name = 'EEG Sonic';
            app.EEGSonicUIFigure.CloseRequestFcn = createCallbackFcn(app, @EEGSonicUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.EEGSonicUIFigure);
            app.FileMenu.Text = 'File';
            app.ConvertEEGDataMenu = uimenu(app.FileMenu);
            app.ConvertEEGDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertEEGDataMenuSelected, true);
            app.ConvertEEGDataMenu.Text = 'Convert EEG Data';
            app.ViewEEGHeadsetMenu = uimenu(app.FileMenu);
            app.ViewEEGHeadsetMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewEEGHeadsetMenuSelected, true);
            app.ViewEEGHeadsetMenu.Text = 'View EEG Headset';
            app.LoadPresetMenu = uimenu(app.FileMenu);
            app.LoadPresetMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPresetMenuSelected, true);
            app.LoadPresetMenu.Text = 'Load Preset';
            app.SavePresetMenu = uimenu(app.FileMenu);
            app.SavePresetMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePresetMenuSelected, true);
            app.SavePresetMenu.Text = 'Save Preset';
            app.SaveDefaultSettingsMenu = uimenu(app.FileMenu);
            app.SaveDefaultSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDefaultSettingsMenuSelected, true);
            app.SaveDefaultSettingsMenu.Text = 'Save Default Settings';
            app.QuitEEGsonicMenu = uimenu(app.FileMenu);
            app.QuitEEGsonicMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitEEGsonicMenuSelected, true);
            app.QuitEEGsonicMenu.Text = 'Quit EEGsonic';
            app.HelpMenu = uimenu(app.EEGSonicUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.BIAPTMenu = uimenu(app.EEGSonicUIFigure);
            app.BIAPTMenu.MenuSelectedFcn = createCallbackFcn(app, @BIAPTMenuSelected, true);
            app.BIAPTMenu.Text = 'BIAPT';
            app.TabGroup = uitabgroup(app.EEGSonicUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.SizeChangedFcn = createCallbackFcn(app, @GeneralTabSizeChanged, true);
            app.GeneralTab.Title = 'General';
            app.GeneralPanel = uipanel(app.GeneralTab);
            app.GeneralPanel.Title = 'General';
            app.SelectSavingDirectoryButton = uibutton(app.GeneralPanel, 'push');
            app.SelectSavingDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSavingDirectoryButtonPushed, true);
            app.SelectSavingDirectoryButton.Text = 'Select Saving Directory';
            app.SavingDirectoryLabel = uilabel(app.GeneralPanel);
            app.SavingDirectoryLabel.Text = 'No saving directory selected';
            app.EEGdeviceButtonGroup = uibuttongroup(app.GeneralPanel);
            app.EEGdeviceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EEGdeviceButtonGroupSelectionChanged, true);
            app.EEGdeviceButtonGroup.Title = 'EEG device';
            app.EGI129Button = uiradiobutton(app.EEGdeviceButtonGroup);
            app.EGI129Button.Text = 'EGI-129';
            app.EGI129Button.Value = true;
            app.ReplayButton = uiradiobutton(app.EEGdeviceButtonGroup);
            app.ReplayButton.Text = 'Replay';
            app.FolderNameEditFieldLabel = uilabel(app.GeneralPanel);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderNameEditField = uieditfield(app.GeneralPanel, 'text');
            app.HeadsetParamTabGroup = uitabgroup(app.GeneralPanel);
            app.EGI129Tab = uitab(app.HeadsetParamTabGroup);
            app.EGI129Tab.Title = 'EGI-129';
            app.DataacquisitionsizeinsecondsEditFieldLabel = uilabel(app.EGI129Tab);
            app.DataacquisitionsizeinsecondsEditFieldLabel.Text = 'Data acquisition size (in seconds)';
            app.EGI129DataSizeEditField = uieditfield(app.EGI129Tab, 'numeric');
            app.EGI129DataSizeEditField.Limits = [1 Inf];
            app.EGI129DataSizeEditField.Editable = 'off';
            app.EGI129DataSizeEditField.Value = 5;
            app.AmplifierIPEditFieldLabel = uilabel(app.EGI129Tab);
            app.AmplifierIPEditFieldLabel.Text = 'Amplifier IP';
            app.EGI129IPEditField = uieditfield(app.EGI129Tab, 'text');
            app.EGI129IPEditField.Value = '10.10.10.51';
            app.SamplingRateEditFieldLabel = uilabel(app.EGI129Tab);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.EGI129SamplingRateEditField = uieditfield(app.EGI129Tab, 'numeric');
            app.EGI129SamplingRateEditField.Limits = [0 Inf];
            app.EGI129SamplingRateEditField.Editable = 'off';
            app.EGI129SamplingRateEditField.Value = 1000;
            app.ReplayTab = uitab(app.HeadsetParamTabGroup);
            app.ReplayTab.Title = 'Replay';
            app.SelectReplayDirectoryButton = uibutton(app.ReplayTab, 'push');
            app.SelectReplayDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReplayDirectoryButtonPushed, true);
            app.SelectReplayDirectoryButton.Text = 'Select Replay Directory';
            app.ReplayDirectoryLabel = uilabel(app.ReplayTab);
            app.ReplayDirectoryLabel.Text = 'No replay directory selected';
            app.OSCReceiversTab = uitab(app.TabGroup);
            app.OSCReceiversTab.Title = 'OSC Receivers';
            app.OSCReceiversPanel = uipanel(app.OSCReceiversTab);
            app.OSCReceiversPanel.Title = 'OSC Receivers';
            app.OSCReceiversListBoxLabel = uilabel(app.OSCReceiversPanel);
            app.OSCReceiversListBoxLabel.Text = 'OSC Receivers';
            app.OSCReceiversListBox = uilistbox(app.OSCReceiversPanel);
            app.OSCReceiversListBox.Items = {};
            app.OSCReceiversListBox.Multiselect = 'on';
            app.OSCReceiversListBox.Value = {};
            app.AddOSCReceiversButton = uibutton(app.OSCReceiversPanel, 'push');
            app.AddOSCReceiversButton.ButtonPushedFcn = createCallbackFcn(app, @AddOSCReceiversButtonPushed, true);
            app.AddOSCReceiversButton.Text = 'Add OSC Receivers';
            app.RemoveOSCReceiversButton = uibutton(app.OSCReceiversPanel, 'push');
            app.RemoveOSCReceiversButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOSCReceiversButtonPushed, true);
            app.RemoveOSCReceiversButton.Text = 'Remove OSC Receivers';
            app.PathEditField = uieditfield(app.OSCReceiversPanel, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = 'DEFAULT';
            app.PortEditFieldLabel = uilabel(app.OSCReceiversPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.OSCReceiversPanel, 'text');
            app.PortEditField.Value = '57120';
            app.SameMachineCheckBox = uicheckbox(app.OSCReceiversPanel);
            app.SameMachineCheckBox.ValueChangedFcn = createCallbackFcn(app, @SameMachineCheckBoxValueChanged, true);
            app.SameMachineCheckBox.Text = 'Yes';
            app.SameMachineCheckBox.Value = true;
            app.OSCReceiverLabel = uilabel(app.OSCReceiversPanel);
            app.OSCReceiverLabel.Text = 'OSC Receiver on same machine?';
            app.SelectOSCReceiversButton = uibutton(app.OSCReceiversPanel, 'push');
            app.SelectOSCReceiversButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOSCReceiversButtonPushed, true);
            app.SelectOSCReceiversButton.Text = 'Select OSC Receivers';
            app.IPAddressEditFieldLabel = uilabel(app.OSCReceiversPanel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.OSCReceiversPanel, 'text');
            app.IPAddressEditField.Value = '127.0.0.1';
            app.Label = uilabel(app.OSCReceiversPanel);
            app.Label.Text = {'Make sure the port is not busy through another application or another '; 'istance of the server application you are sending the data to.'};
            app.SpectralPowerRatioTab = uitab(app.TabGroup);
            app.SpectralPowerRatioTab.Title = 'Spectral Power Ratio';
            app.SpectralPowerRatioPanel = uipanel(app.SpectralPowerRatioTab);
            app.SpectralPowerRatioPanel.Title = 'Spectral Power Ratio';
            app.WindowSizeinsecondsEditFieldLabel = uilabel(app.SpectralPowerRatioPanel);
            app.WindowSizeinsecondsEditFieldLabel.Text = 'Window Size (in seconds)';
            app.SPRWindowSizeEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRWindowSizeEditField.Limits = [0 Inf];
            app.SPRWindowSizeEditField.Value = 2;
            app.TimeBandwithProductLabel = uilabel(app.SpectralPowerRatioPanel);
            app.TimeBandwithProductLabel.Text = 'Time Bandwith Product';
            app.SPRTimeBandwithProductEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRTimeBandwithProductEditField.Limits = [0 Inf];
            app.SPRTimeBandwithProductEditField.Value = 2;
            app.RequiredSizeinsecondsLabel = uilabel(app.SpectralPowerRatioPanel);
            app.RequiredSizeinsecondsLabel.Text = 'Required Size (in seconds)';
            app.SPRRequiredSizeEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRRequiredSizeEditField.Limits = [0 Inf];
            app.SPRRequiredSizeEditField.Value = 5;
            app.NumberofTapersLabel = uilabel(app.SpectralPowerRatioPanel);
            app.NumberofTapersLabel.Text = 'Number of Tapers';
            app.SPRNumberTapersEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRNumberTapersEditField.Limits = [0 Inf];
            app.SPRNumberTapersEditField.Value = 3;
            app.ThetaBandwithinHzEditFieldLabel = uilabel(app.SpectralPowerRatioPanel);
            app.ThetaBandwithinHzEditFieldLabel.Text = 'Theta Bandwith (in Hz)';
            app.SPRThetaBandwithEditField = uieditfield(app.SpectralPowerRatioPanel, 'text');
            app.SPRThetaBandwithEditField.Value = '[4,8]';
            app.AlphaBandwithinHzLabel_2 = uilabel(app.SpectralPowerRatioPanel);
            app.AlphaBandwithinHzLabel_2.Text = 'Alpha Bandwith (in Hz)';
            app.SPRAlphaBandwithEditField = uieditfield(app.SpectralPowerRatioPanel, 'text');
            app.SPRAlphaBandwithEditField.Value = '[8,13]';
            app.BetaBandwithinHzLabel_2 = uilabel(app.SpectralPowerRatioPanel);
            app.BetaBandwithinHzLabel_2.Text = 'Beta Bandwith (in Hz)';
            app.SPRBetaBandwithEditField = uieditfield(app.SpectralPowerRatioPanel, 'text');
            app.SPRBetaBandwithEditField.Value = '[13,25]';
            app.StepsizeinsecondsLabel = uilabel(app.SpectralPowerRatioPanel);
            app.StepsizeinsecondsLabel.Text = 'Step size (in seconds)';
            app.SPRStepSizeEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRStepSizeEditField.Limits = [0 Inf];
            app.SPRStepSizeEditField.Value = 0.5;
            app.OSCsendfrequencyLabel = uilabel(app.SpectralPowerRatioPanel);
            app.OSCsendfrequencyLabel.Text = 'OSC send frequency';
            app.donttouchLabel = uilabel(app.SpectralPowerRatioPanel);
            app.donttouchLabel.Text = {'don''t change the parameters'; 'window size, step size, time bandwidth'; 'product and number of tapers, '; 'they are interdependent'};
            app.Thisfeaturereturns2globalspectralratiosLabel = uilabel(app.SpectralPowerRatioPanel);
            app.Thisfeaturereturns2globalspectralratiosLabel.Text = {'1. BETA power / ALPHA power'; ''; '2. ALPHA power / THETA power'};
            app.Thisfeatureoutputs2globalspectralratiosLabel = uilabel(app.SpectralPowerRatioPanel);
            app.Thisfeatureoutputs2globalspectralratiosLabel.Text = 'This feature outputs 2 global spectral ratios:';
            app.TopographicDistributionTab = uitab(app.TabGroup);
            app.TopographicDistributionTab.Title = 'Topographic Distribution';
            app.TopographicDistributionPanel = uipanel(app.TopographicDistributionTab);
            app.TopographicDistributionPanel.Title = 'Topographic Distribution';
            app.WindowSizeinsecondsLabel = uilabel(app.TopographicDistributionPanel);
            app.WindowSizeinsecondsLabel.Text = 'Window Size (in seconds)';
            app.TDRequiredSizeEditField = uieditfield(app.TopographicDistributionPanel, 'numeric');
            app.TDRequiredSizeEditField.Limits = [0 Inf];
            app.TDRequiredSizeEditField.Value = 5;
            app.FrequencyinHzLabel = uilabel(app.TopographicDistributionPanel);
            app.FrequencyinHzLabel.Text = 'Frequency (in Hz)';
            app.TDFrequencyEditField = uieditfield(app.TopographicDistributionPanel, 'numeric');
            app.TDFrequencyEditField.Limits = [0 50];
            app.ChannelsSelectionholdCtrlformulitpleLabel = uilabel(app.TopographicDistributionPanel);
            app.ChannelsSelectionholdCtrlformulitpleLabel.Text = {'Channels Selection (hold Ctrl for mulitple)'; ''; ''; ''};
            app.TDChannelsListBox = uilistbox(app.TopographicDistributionPanel);
            app.TDChannelsListBox.Items = {};
            app.TDChannelsListBox.Multiselect = 'on';
            app.TDChannelsListBox.Value = {};
            app.OSCsendfrequencyLabel_2 = uilabel(app.TopographicDistributionPanel);
            app.OSCsendfrequencyLabel_2.Text = 'OSC send frequency';
            app.default10HzadjusttopatientLabel = uilabel(app.TopographicDistributionPanel);
            app.default10HzadjusttopatientLabel.Text = 'default 10 Hz (adjust to patient)';
            app.ThisfeatureoutputstherationLabel = uilabel(app.TopographicDistributionPanel);
            app.ThisfeatureoutputstherationLabel.Text = 'This feature outputs the ration:';
            app.frontpowerbackpowerLabel = uilabel(app.TopographicDistributionPanel);
            app.frontpowerbackpowerLabel.Text = ' front power / back power';
            app.PhaseAmplitudeCouplingTab = uitab(app.TabGroup);
            app.PhaseAmplitudeCouplingTab.Title = 'Phase Amplitude Coupling';
            app.PhaseAmplitudeCouplingPanel = uipanel(app.PhaseAmplitudeCouplingTab);
            app.PhaseAmplitudeCouplingPanel.Title = 'Phase Amplitude Coupling';
            app.WindowSizeinsecondsEditFieldLabel_3 = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.WindowSizeinsecondsEditFieldLabel_3.Text = 'Window Size (in seconds)';
            app.PACRequiredSizeEditField = uieditfield(app.PhaseAmplitudeCouplingPanel, 'numeric');
            app.PACRequiredSizeEditField.Limits = [0 Inf];
            app.PACRequiredSizeEditField.Value = 30;
            app.ExtraLowFrequencyinHzLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.ExtraLowFrequencyinHzLabel.Text = 'Extra Low Frequency (in Hz)';
            app.PACExtraLowFrequencyBandwithEditField = uieditfield(app.PhaseAmplitudeCouplingPanel, 'text');
            app.AlphainHzLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.AlphainHzLabel.Text = 'Alpha (in Hz)';
            app.PACHighFrequencyBandwithEditField = uieditfield(app.PhaseAmplitudeCouplingPanel, 'text');
            app.OSCsendfrequencyLabel_3 = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.OSCsendfrequencyLabel_3.Text = 'OSC send frequency';
            app.setto01to1HzLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.setto01to1HzLabel.Text = 'set to [0.1 to 1] Hz]';
            app.setto813HzLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.setto813HzLabel.Text = 'set to [8 - 13] Hz';
            app.Sends2numbersfrontalparietralLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.Sends2numbersfrontalparietralLabel.Text = 'This feature outputs 2 averages for the phase amplitude coupling of ';
            app.thefrontalelectrodes2theparietalelectrodesLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.thefrontalelectrodes2theparietalelectrodesLabel.Text = {'1.) the frontal electrodes, '; '2.) the parietal electrodes '};
            app.SelectChannelsholdCtrlformultiselectionLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.SelectChannelsholdCtrlformultiselectionLabel.Text = 'Select Channels (hold Ctrl for multi-selection)';
            app.PACChannelsListBox = uilistbox(app.PhaseAmplitudeCouplingPanel);
            app.PACChannelsListBox.Items = {};
            app.PACChannelsListBox.Multiselect = 'on';
            app.PACChannelsListBox.Value = {};
            app.FrontoparietalwPLITab = uitab(app.TabGroup);
            app.FrontoparietalwPLITab.Title = 'Frontoparietal wPLI';
            app.FrontoparietalwPLIPanel = uipanel(app.FrontoparietalwPLITab);
            app.FrontoparietalwPLIPanel.Title = 'Frontoparietal wPLI';
            app.WindowSizeinsecondsEditFieldLabel_4 = uilabel(app.FrontoparietalwPLIPanel);
            app.WindowSizeinsecondsEditFieldLabel_4.Text = 'Window Size (in seconds)';
            app.FPwPLIRequiredSizeEditField = uieditfield(app.FrontoparietalwPLIPanel, 'numeric');
            app.FPwPLIRequiredSizeEditField.Limits = [0 Inf];
            app.FPwPLIRequiredSizeEditField.Value = 10;
            app.SelectChannelsholdCtrlformultiselectionLabel_2 = uilabel(app.FrontoparietalwPLIPanel);
            app.SelectChannelsholdCtrlformultiselectionLabel_2.Text = 'Select Channels (hold Ctrl for multi-selection)';
            app.FPwPLIChannelsListBox = uilistbox(app.FrontoparietalwPLIPanel);
            app.FPwPLIChannelsListBox.Items = {};
            app.FPwPLIChannelsListBox.Multiselect = 'on';
            app.FPwPLIChannelsListBox.Value = {};
            app.NumberofSurrogatesLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.NumberofSurrogatesLabel.Text = 'Number of Surrogates';
            app.FPwPLINumberSurrogatesEditField = uieditfield(app.FrontoparietalwPLIPanel, 'numeric');
            app.FPwPLINumberSurrogatesEditField.Limits = [0 20];
            app.pValueLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.pValueLabel.Text = 'p Value';
            app.FPwPLIPValueEditField = uieditfield(app.FrontoparietalwPLIPanel, 'numeric');
            app.FPwPLIPValueEditField.Limits = [0 1];
            app.FPwPLIPValueEditField.Value = 0.05;
            app.FPwPLIPValueCheckBox = uicheckbox(app.FrontoparietalwPLIPanel);
            app.FPwPLIPValueCheckBox.Text = '';
            app.FPwPLIPValueCheckLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.FPwPLIPValueCheckLabel.Text = 'Enable Surrogates Analysis';
            app.OSCsendfrequencyLabel_4 = uilabel(app.FrontoparietalwPLIPanel);
            app.OSCsendfrequencyLabel_4.Text = 'OSC send frequency';
            app.setto10CPUexpensiveLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.setto10CPUexpensiveLabel.Text = 'set to 10 (CPU expensive)';
            app.typicallysetto05Label = uilabel(app.FrontoparietalwPLIPanel);
            app.typicallysetto05Label.Text = 'typically set to 0.5';
            app.uncheckiftooCPUheavyLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.uncheckiftooCPUheavyLabel.Text = 'uncheck if too CPU heavy';
            app.outputsLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.outputsLabel.Text = '4 outputs: ';
            app.leftlateralleftmidlinerightlateralrightmidlineLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.leftlateralleftmidlinerightlateralrightmidlineLabel.Text = 'left lateral, left midline, right lateral, right midline';
            app.wPLIanddPLIshopuldhavethesamesettingsLabel_2 = uilabel(app.FrontoparietalwPLITab);
            app.wPLIanddPLIshopuldhavethesamesettingsLabel_2.Text = 'wPLI and dPLI shopuld have the same settings';
            app.FrontoparietaldPLITab = uitab(app.TabGroup);
            app.FrontoparietaldPLITab.Title = 'Frontoparietal dPLI';
            app.FrontoparietaldPLIPanel = uipanel(app.FrontoparietaldPLITab);
            app.FrontoparietaldPLIPanel.Title = 'Frontoparietal dPLI';
            app.WindowSizeinsecondsEditFieldLabel_5 = uilabel(app.FrontoparietaldPLIPanel);
            app.WindowSizeinsecondsEditFieldLabel_5.Text = 'Window Size (in seconds)';
            app.FPdPLIRequiredSizeEditField = uieditfield(app.FrontoparietaldPLIPanel, 'numeric');
            app.FPdPLIRequiredSizeEditField.Limits = [0 Inf];
            app.FPdPLIRequiredSizeEditField.Value = 10;
            app.SelectChannelsholdCtrlformultiselectionLabel_3 = uilabel(app.FrontoparietaldPLIPanel);
            app.SelectChannelsholdCtrlformultiselectionLabel_3.Text = {'Select Channels (hold Ctrl for multi-selection)'; ''};
            app.FPdPLIChannelsListBox = uilistbox(app.FrontoparietaldPLIPanel);
            app.FPdPLIChannelsListBox.Items = {};
            app.FPdPLIChannelsListBox.Multiselect = 'on';
            app.FPdPLIChannelsListBox.Value = {};
            app.NumberofSurrogatesLabel_2 = uilabel(app.FrontoparietaldPLIPanel);
            app.NumberofSurrogatesLabel_2.Text = 'Number of Surrogates';
            app.FPdPLINumberSurrogatesEditField = uieditfield(app.FrontoparietaldPLIPanel, 'numeric');
            app.FPdPLINumberSurrogatesEditField.Limits = [0 20];
            app.pValueLabel_2 = uilabel(app.FrontoparietaldPLIPanel);
            app.pValueLabel_2.Text = 'p Value';
            app.FPdPLIPValueEditField = uieditfield(app.FrontoparietaldPLIPanel, 'numeric');
            app.FPdPLIPValueEditField.Limits = [0 Inf];
            app.FPdPLIPValueEditField.Value = 0.05;
            app.FPdPLIPValueCheckBox = uicheckbox(app.FrontoparietaldPLIPanel);
            app.FPdPLIPValueCheckBox.Text = '';
            app.FPdPLIPValueCheckLabel = uilabel(app.FrontoparietaldPLIPanel);
            app.FPdPLIPValueCheckLabel.Text = 'Enable Surrogates Analysis';
            app.OSCsendfrequencyLabel_5 = uilabel(app.FrontoparietaldPLIPanel);
            app.OSCsendfrequencyLabel_5.Text = 'OSC send frequency';
            app.setto10CPUexpensiveLabel_2 = uilabel(app.FrontoparietaldPLIPanel);
            app.setto10CPUexpensiveLabel_2.Text = 'set to 10 CPU expensive';
            app.typicallysetto05Label_2 = uilabel(app.FrontoparietaldPLIPanel);
            app.typicallysetto05Label_2.Text = 'typically set to 0.5';
            app.uncheckiftooCPUheavyLabel_2 = uilabel(app.FrontoparietaldPLIPanel);
            app.uncheckiftooCPUheavyLabel_2.Text = 'uncheck if too CPU heavy';
            app.outputsLabel_2 = uilabel(app.FrontoparietaldPLIPanel);
            app.outputsLabel_2.Text = '4 outputs: ';
            app.leftlateralleftmidlinerightlateralrightmidlineLabel_2 = uilabel(app.FrontoparietaldPLIPanel);
            app.leftlateralleftmidlinerightlateralrightmidlineLabel_2.Text = 'left lateral, left midline, right lateral, right midline';
            app.wPLIanddPLIshopuldhavethesamesettingsLabel = uilabel(app.FrontoparietaldPLITab);
            app.wPLIanddPLIshopuldhavethesamesettingsLabel.Text = 'wPLI and dPLI shopuld have the same settings';
            app.HubLocationTab = uitab(app.TabGroup);
            app.HubLocationTab.Title = 'Hub Location';
            app.HubLocationPanel = uipanel(app.HubLocationTab);
            app.HubLocationPanel.Title = 'Hub Location';
            app.NumberofSurrogatesLabel_3 = uilabel(app.HubLocationPanel);
            app.NumberofSurrogatesLabel_3.Text = 'Number of Surrogates';
            app.HLNumberSurrogatesEditField = uieditfield(app.HubLocationPanel, 'numeric');
            app.HLNumberSurrogatesEditField.Limits = [0 20];
            app.WindowSizeinsecondsEditFieldLabel_6 = uilabel(app.HubLocationPanel);
            app.WindowSizeinsecondsEditFieldLabel_6.Text = 'Window Size (in seconds)';
            app.HLRequiredSizeEditField = uieditfield(app.HubLocationPanel, 'numeric');
            app.HLRequiredSizeEditField.Limits = [0 Inf];
            app.HLRequiredSizeEditField.Value = 30;
            app.pValueLabel_3 = uilabel(app.HubLocationPanel);
            app.pValueLabel_3.Text = 'p Value';
            app.HLPValueEditField = uieditfield(app.HubLocationPanel, 'numeric');
            app.HLPValueEditField.Limits = [0 1];
            app.HLPValueEditField.Value = 0.05;
            app.TopConnectionThreshold0010Label = uilabel(app.HubLocationPanel);
            app.TopConnectionThreshold0010Label.Text = 'Top Connection Threshold (0.0 - 1.0)';
            app.HLTopConnectionThresholdEditField = uieditfield(app.HubLocationPanel, 'numeric');
            app.HLTopConnectionThresholdEditField.Limits = [0 1];
            app.OutputTypeDropDownLabel = uilabel(app.HubLocationPanel);
            app.OutputTypeDropDownLabel.Text = 'Output Type';
            app.HLOutputTypeDropDown = uidropdown(app.HubLocationPanel);
            app.HLOutputTypeDropDown.Items = {'index'};
            app.HLOutputTypeDropDown.Value = 'index';
            app.HLPValueCheckBox = uicheckbox(app.HubLocationPanel);
            app.HLPValueCheckBox.Text = '';
            app.HLPValueCheckLabel = uilabel(app.HubLocationPanel);
            app.HLPValueCheckLabel.Text = 'Enable Surrogates Analysis';
            app.HLSendGraphCheckBox = uicheckbox(app.HubLocationPanel);
            app.HLSendGraphCheckBox.Text = '';
            app.HLSendGraphCheckLabel = uilabel(app.HubLocationPanel);
            app.HLSendGraphCheckLabel.Text = 'Send Hub Graph as OSC';
            app.OSCsendfrequencyLabel_6 = uilabel(app.HubLocationPanel);
            app.OSCsendfrequencyLabel_6.Text = 'OSC send frequency';
            app.settoo10Label = uilabel(app.HubLocationPanel);
            app.settoo10Label.Text = 'set too 10';
            app.typicallysetto005Label = uilabel(app.HubLocationPanel);
            app.typicallysetto005Label.Text = 'typically set to 0.05';
            app.typicallysetto005Label_2 = uilabel(app.HubLocationPanel);
            app.typicallysetto005Label_2.Text = 'typically set to 0.05';
            app.whetherLabel = uilabel(app.HubLocationPanel);
            app.whetherLabel.Text = 'whether ';
            app.checkifLabel = uilabel(app.HubLocationPanel);
            app.checkifLabel.Text = 'check if ...';
            app.sendslistofconnectionsLabel = uilabel(app.HubLocationPanel);
            app.sendslistofconnectionsLabel.Text = 'sends list of connections';
            app.outputtheaveragelocationofthehighestdegreenodeLabel = uilabel(app.HubLocationPanel);
            app.outputtheaveragelocationofthehighestdegreenodeLabel.Text = 'output the average location of the highest degree node';
            app.PermutationEntropyTab = uitab(app.TabGroup);
            app.PermutationEntropyTab.Title = 'Permutation Entropy';
            app.PermutationEntropyPanel = uipanel(app.PermutationEntropyTab);
            app.PermutationEntropyPanel.Title = 'Permutation Entropy';
            app.WindowSizeinsecondsEditFieldLabel_7 = uilabel(app.PermutationEntropyPanel);
            app.WindowSizeinsecondsEditFieldLabel_7.Text = 'Window Size (in seconds)';
            app.PERequiredSizeEditField = uieditfield(app.PermutationEntropyPanel, 'numeric');
            app.PERequiredSizeEditField.Limits = [0 Inf];
            app.PERequiredSizeEditField.Value = 10;
            app.SelectChannelsholdCtrlformultiselectionLabel_4 = uilabel(app.PermutationEntropyPanel);
            app.SelectChannelsholdCtrlformultiselectionLabel_4.Text = 'Select Channels (hold Ctrl for multi-selection)';
            app.PEChannelsListBox = uilistbox(app.PermutationEntropyPanel);
            app.PEChannelsListBox.Items = {};
            app.PEChannelsListBox.Multiselect = 'on';
            app.PEChannelsListBox.Value = {};
            app.EmbeddingDimensionLabel = uilabel(app.PermutationEntropyPanel);
            app.EmbeddingDimensionLabel.Text = 'Embedding Dimension';
            app.PEEmbeddingDimensionEditField = uieditfield(app.PermutationEntropyPanel, 'numeric');
            app.PEEmbeddingDimensionEditField.Limits = [0 Inf];
            app.PEEmbeddingDimensionEditField.ValueDisplayFormat = '%.0f';
            app.TimeDelayinsecondsLabel = uilabel(app.PermutationEntropyPanel);
            app.TimeDelayinsecondsLabel.Text = 'Time Delay (in seconds)';
            app.PETimeDelayEditField = uieditfield(app.PermutationEntropyPanel, 'numeric');
            app.PETimeDelayEditField.Limits = [0 Inf];
            app.PETimeDelayEditField.ValueDisplayFormat = '%.0f';
            app.OSCsendfrequencyLabel_7 = uilabel(app.PermutationEntropyPanel);
            app.OSCsendfrequencyLabel_7.Text = 'OSC send frequency';
            app.typicallysetto5Label = uilabel(app.PermutationEntropyPanel);
            app.typicallysetto5Label.Text = 'typically set to 5 ';
            app.typicallysetto4Label = uilabel(app.PermutationEntropyPanel);
            app.typicallysetto4Label.Text = 'typically set to 4';
            app.ThisfeatureoutputstwonumbersLabel = uilabel(app.PermutationEntropyPanel);
            app.ThisfeatureoutputstwonumbersLabel.Text = 'This feature outputs two numbers: ';
            app.oneforthefrontandonefoirthebackLabel = uilabel(app.PermutationEntropyPanel);
            app.oneforthefrontandonefoirthebackLabel.Text = 'one for the front and one foir the back';
            app.EEGSonicPanel = uipanel(app.EEGSonicUIFigure);
            app.EEGSonicPanel.Title = 'EEGSonic';
            app.LaunchButton = uibutton(app.EEGSonicPanel, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.FeedbackTextField = uitextarea(app.EEGSonicPanel);
            app.FeedbackTextField.Editable = 'off';
            app.FeedbackTextField.Value = {'Feedback Area'; ''};
            app.SpectralPowerRatioCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.SpectralPowerRatioCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpectralPowerRatioCheckBoxValueChanged, true);
            app.SpectralPowerRatioCheckBox.Text = '';
            app.TopographicDistributionCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.TopographicDistributionCheckBox.ValueChangedFcn = createCallbackFcn(app, @TopographicDistributionCheckBoxValueChanged, true);
            app.TopographicDistributionCheckBox.Text = '';
            app.PhaseAmplitudeCouplingCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.PhaseAmplitudeCouplingCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaseAmplitudeCouplingCheckBoxValueChanged, true);
            app.PhaseAmplitudeCouplingCheckBox.Text = '';
            app.FrontoparietalwPLICheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.FrontoparietalwPLICheckBox.ValueChangedFcn = createCallbackFcn(app, @FrontoparietalwPLICheckBoxValueChanged, true);
            app.FrontoparietalwPLICheckBox.Text = '';
            app.FrontoparietaldPLICheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.FrontoparietaldPLICheckBox.ValueChangedFcn = createCallbackFcn(app, @FrontoparietaldPLICheckBoxValueChanged, true);
            app.FrontoparietaldPLICheckBox.Text = '';
            app.HubLocationCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.HubLocationCheckBox.ValueChangedFcn = createCallbackFcn(app, @HubLocationCheckBoxValueChanged, true);
            app.HubLocationCheckBox.Text = '';
            app.PermutationEntropyCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.PermutationEntropyCheckBox.ValueChangedFcn = createCallbackFcn(app, @PermutationEntropyCheckBoxValueChanged, true);
            app.PermutationEntropyCheckBox.Text = '';
            app.DebugModeSwitchLabel = uilabel(app.EEGSonicUIFigure);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.EEGSonicUIFigure, 'slider');
            app.DebugModeSwitch.Value = 'On';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGsonic_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGSonicUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGSonicUIFigure)
        end
    end
end