classdef replayer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DocumentationViewer  matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        TabGroup             matlab.ui.container.TabGroup
        ReplayerTab          matlab.ui.container.Tab
        DataSliderLabel      matlab.ui.control.Label
        DataSlider           matlab.ui.control.Slider
        StartSliderLabel     matlab.ui.control.Label
        StartSlider          matlab.ui.control.Slider
        StopSliderLabel      matlab.ui.control.Label
        StopSlider           matlab.ui.control.Slider
        InformationTab       matlab.ui.container.Tab
        PauseButton          matlab.ui.control.StateButton
    end


    properties (Access = public)
        main_application; % link to EEGsonic_app
    end

    properties (Access = private)
        % Structure containing paths
        play;
        parameters;
        information;
        loading_directory;
        data_directory;
        parameters_directory;
    end

    methods (Access = private)
        %
    end

    methods (Access = public)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup Base Directory
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
          
            % Setting up Variables
            app_data = load(strcat(base_directory,'/../data/app_data.mat'));
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            app.information.base_directory = base_directory;
            app.loading_directory = app.parameters.general.replay.replay_directory;
            app.data_directory = strcat(app.loading_directory,filesep,"data");
            app.parameters_directory = strcat(app.loading_directory,filesep,"parameters");
            
            % Load and set the information and parameters to be equal to those of the previous recording
            app_data = load(strcat(app.parameters_directory,'/app_data.mat'));
            app_data = app_data.app_data;
            app.parameters = app_data.parameters;
            app.information = app_data.information;
        end

        % Close request function: DocumentationViewer
        function DocumentationViewerCloseRequest(app, event)
            app.main_application.is_replayer_running = 0;
            delete(app);
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if(value == 1)
                app.play = 1;
                app.PauseButton.Text = "Play";
            else
                app.play = 0;
                app.PauseButton.Text = "Pause";
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DocumentationViewer = uifigure;
            app.DocumentationViewer.Name = 'Documentation Viewer';
            app.DocumentationViewer.CloseRequestFcn = createCallbackFcn(app, @DocumentationViewerCloseRequest, true);
            app.Menu = uimenu(app.DocumentationViewer);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.DocumentationViewer);
            app.ReplayerTab = uitab(app.TabGroup);
            app.ReplayerTab.Title = 'Replayer';
            app.DataSliderLabel = uilabel(app.ReplayerTab);
            app.DataSliderLabel.Text = 'Data';
            app.DataSlider = uislider(app.ReplayerTab);
            app.DataSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.StartSliderLabel = uilabel(app.ReplayerTab);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ReplayerTab);
            app.StartSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.StopSliderLabel = uilabel(app.ReplayerTab);
            app.StopSliderLabel.Text = 'Stop';
            app.StopSlider = uislider(app.ReplayerTab);
            app.StopSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.StopSlider.Value = 100;
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.PauseButton = uibutton(app.DocumentationViewer, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
        end
    end

    methods (Access = public)

        % Construct app
        function app = replayer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DocumentationViewer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DocumentationViewer)
        end
    end
end